classdef Keithley_Stimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        PulseTab                      matlab.ui.container.Tab
        PolarityButtonGroup           matlab.ui.container.ButtonGroup
        CathodicfirstButton           matlab.ui.control.ToggleButton
        AnodicfirstButton             matlab.ui.control.ToggleButton
        ButtonGroup_2                 matlab.ui.container.ButtonGroup
        ButtonGroup_3                 matlab.ui.container.ButtonGroup
        ButtonGroup_4                 matlab.ui.container.ButtonGroup
        ComplianceVLabel              matlab.ui.control.Label
        CheckBox_3                    matlab.ui.control.CheckBox
        FrequencyHzEditFieldLabel     matlab.ui.control.Label
        InterphasegapEditFieldLabel   matlab.ui.control.Label
        InterphasegapEditField        matlab.ui.control.NumericEditField
        NumberofcyclesEditFieldLabel  matlab.ui.control.Label
        NumberofcyclesEditField       matlab.ui.control.NumericEditField
        ORdurationsEditFieldLabel     matlab.ui.control.Label
        ORdurationsEditField          matlab.ui.control.NumericEditField
        PhaseamplitudeuALabel         matlab.ui.control.Label
        CathodicEditFieldLabel        matlab.ui.control.Label
        CathodicEditField             matlab.ui.control.NumericEditField
        AnodicEditFieldLabel          matlab.ui.control.Label
        AnodicEditField               matlab.ui.control.NumericEditField
        PhasedurationusLabel          matlab.ui.control.Label
        CathodicEditField_2Label      matlab.ui.control.Label
        CathodicEditField_2           matlab.ui.control.NumericEditField
        AnodicEditField_2Label        matlab.ui.control.Label
        AnodicEditField_2             matlab.ui.control.NumericEditField
        ArmwaveformButton             matlab.ui.control.Button
        FrequencyHzEditField          matlab.ui.control.NumericEditField
        AuthorCeliaFernandezBrilletCodeindevelopmentasofJan2023Label  matlab.ui.control.Label
        DCTab                         matlab.ui.container.Tab
        ComplianceVLabel_2            matlab.ui.control.Label
        PolarityButtonGroup_2         matlab.ui.container.ButtonGroup
        CathodicButton                matlab.ui.control.ToggleButton
        AnodicButton                  matlab.ui.control.ToggleButton
        ButtonGroup_5                 matlab.ui.container.ButtonGroup
        AmplitudeuALabel              matlab.ui.control.Label
        AmplitudeEditField            matlab.ui.control.NumericEditField
        ArmwaveformButton_2           matlab.ui.control.Button
        ButtonGroup_6                 matlab.ui.container.ButtonGroup
        AmplitudeuALabel_2            matlab.ui.control.Label
        DurationEditField             matlab.ui.control.NumericEditField
        FilterButtonGroup             matlab.ui.container.ButtonGroup
        OnButton                      matlab.ui.control.RadioButton
        OffButton                     matlab.ui.control.RadioButton
        ComplianceVEditField          matlab.ui.control.NumericEditField
        InnershieldButtonGroup        matlab.ui.container.ButtonGroup
        LowButton                     matlab.ui.control.RadioButton
        GuardButton                   matlab.ui.control.RadioButton
        OutputLowButtonGroup          matlab.ui.container.ButtonGroup
        FloatButton                   matlab.ui.control.RadioButton
        EarthButton                   matlab.ui.control.RadioButton
        Image                         matlab.ui.control.Image
        ConnectportButton             matlab.ui.control.Button
        idnButton                     matlab.ui.control.Button
        DisconnectButton              matlab.ui.control.Button
        InitializewaveformButton      matlab.ui.control.Button
        AbortwaveformButton           matlab.ui.control.Button
        RangeuAEditFieldLabel         matlab.ui.control.Label
        RangeuAEditField              matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ser % serial object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectportButton
        function ConnectportButtonValueChanged(app, event)
            set(app.ConnectportButton,'Enable','off')
            set(app.idnButton,'Enable','on')
            set(app.DisconnectButton,'Enable','on')
            %set(app.ConnectportButton,'Enable','off')
            % Delete instrument objects that are still open
            delete(instrfindall)
            % Create serial port object 'ser'. Specify COM port number and
            % Baud rate. This opens a serial connection to the Keithley.
            app.ser = serialport('COM13', 115200)
            % Set line terminator
            configureTerminator(app.ser, "LF")
            % Set size of receiving buffer, if needed.
            %set(app.ser, 'InputBufferSize', 30000);
            writeline(app.ser, 'sour:wave:abor')
        end

        % Button pushed function: idnButton
        function idnButtonValueChanged(app, event)
            % Transmit data to the server (or a request for data from the server).
            writeline(app.ser, '*idn?');
            pause(1); % Pause for the communication delay, if needed.
            fig = uifigure;
            uialert(fig,readline(app.ser),'Response from Keithley','Icon','info');
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonValueChanged(app, event)
            set(app.ConnectportButton,'Enable','on')
            set(app.idnButton,'Enable','off')
            set(app.DisconnectButton,'Enable','off')
            writeline(app.ser, 'output off')
            delete(app.ser)
            clear app.ser
        end

        % Value changed function: ComplianceVEditField
        function ComplianceVEditFieldValueChanged(app, event)
            value = app.ComplianceVEditField.Value;
            writeline(app.ser, sprintf('curr:comp %d',value))
        end

        % Value changed function: NumberofcyclesEditField
        function NumberofcyclesEditFieldValueChanged(app, event)
            value = app.NumberofcyclesEditField.Value;
            app.ORdurationsEditField.Value = 0;
            writeline(app.ser, sprintf('sour:wave:dur:cycl %d',value))
        end

        % Value changed function: ORdurationsEditField
        function ORdurationsEditFieldValueChanged(app, event)
            value = app.ORdurationsEditField.Value;
            app.NumberofcyclesEditField.Value = 0;
            writeline(app.ser, sprintf('sour:wave:dur:time %d',value))
            
        end

        % Value changed function: CheckBox_3
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox_3.Value; % For number of cycles
            if value == 1
                set(app.NumberofcyclesEditField,'Enable','on')
                set(app.ORdurationsEditField,'Enable','off')
            else
                set(app.NumberofcyclesEditField,'Enable','off')
                set(app.ORdurationsEditField,'Enable','on')
            end
        end

        % Button pushed function: ArmwaveformButton
        function ArmwaveformButton_2ValueChanged(app, event)
            % For pulses
            writeline(app.ser, 'sour:wave:abor')
            % Filtering
            if app.OnButton.Value
                writeline(app.ser, 'curr:filt on')
            else
                writeline(app.ser, 'curr:filt off')
            end
            % Inner shield (BLACK)
            if app.GuardButton.Value
                writeline(app.ser, 'outp:ishield guard')
            else
                writeline(app.ser, 'outp:ishield olow')
            end
            % Output low (GREEN)
            if app.EarthButton.Value
                writeline(app.ser, 'outp:ltearth on')
            else
                writeline(app.ser, 'outp:ltearth off')
            end
            % Range
%             if app.CheckBox_4.Value
%                 writeline(app.ser, 'sour:curr:rang auto')
%             else
%                 writeline(app.ser, sprintf('sour:curr:rang %de-6',app.RangeuAEditField.Value))
%             end
            % Compliance
            writeline(app.ser, sprintf('curr:comp %d',app.ComplianceVEditField.Value))
            % Frequency
            writeline(app.ser, sprintf('sour:wave:freq %d',app.FrequencyHzEditField.Value))
            % Amplitude
            writeline(app.ser, sprintf('sour:wave:ampl %de-6', max(app.AnodicEditField.Value, app.CathodicEditField.Value)))
            % Duration
            if app.CheckBox_3.Value == 1
                set(app.NumberofcyclesEditField,'Enable','on')
                set(app.ORdurationsEditField,'Enable','off')
                app.ORdurationsEditField.Value = 0;
                writeline(app.ser, sprintf('sour:wave:dur:cycl %d',app.NumberofcyclesEditField.Value))
            else
                set(app.NumberofcyclesEditField,'Enable','off')
                set(app.ORdurationsEditField,'Enable','on')
                app.NumberofcyclesEditField.Value = 0;
                writeline(app.ser, sprintf('sour:wave:dur:time %d',app.ORdurationsEditField.Value))
            end

            % Generation of waveform string
            freq = app.FrequencyHzEditField.Value;
            cath_amp = abs(app.CathodicEditField.Value);
            anod_amp = abs(app.AnodicEditField.Value);
            max_amp = max(cath_amp,anod_amp);
            cath_dur = abs(app.CathodicEditField_2.Value);
            anod_dur = abs(app.AnodicEditField_2.Value);
            iscathfirst = app.CathodicfirstButton.Value;
            
            s = (10^6)/freq/min(cath_dur,anod_dur) % number of slots
            res = ((10^6)/freq)/s
            str_wave = zeros(1,s)
            if iscathfirst 
                str_wave(1:2) = [-cath_amp/max_amp, anod_amp/max_amp];
            else
                str_wave(1:2) = [anod_amp/max_amp, -cath_amp/max_amp];
            end
            str_wave
            
            if app.CheckBox_3.Value % duration in terms of cycles
                x  = 0:s*app.NumberofcyclesEditField.Value;
                x(end) = [];
                y = abs(app.AmplitudeEditField.Value)*repmat(str_wave,1,app.NumberofcyclesEditField.Value);
            else
                x = 0:(1/freq/s):app.ORdurationsEditField.Value;
                x(end) = [];
                y = abs(app.AmplitudeEditField.Value)*repmat(str_wave,1,app.ORdurationsEditField.Value*freq);
            end
            
            plot(app.UIAxes,x,y)
            ylim(app.UIAxes,[-1.5*abs(app.AmplitudeEditField.Value) 1.5*abs(app.AmplitudeEditField.Value)])
            xlim(app.UIAxes,[-4, app.DurationEditField.Value+4])
            
            str_send = 'sour:wave:arb:data ';
            for i = 1:length(str_wave)
                str_send = [str_send num2str(str_wave(i)) ','];
            end
            str_send(end) = [];

            writeline(app.ser, str_send)
            writeline(app.ser, 'sour:wave:func arb0')
            writeline(app.ser, 'sour:wave:arm')


        end

        % Button pushed function: InitializewaveformButton
        function InitializewaveformButton_2ValueChanged(app, event)
            writeline(app.ser, 'sour:wave:init')
        end

        % Button pushed function: AbortwaveformButton
        function AbortwaveformButtonValueChanged(app, event)
            writeline(app.ser, 'sour:wave:abor')
        end

        % Selection changed function: FilterButtonGroup
        function FilterButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterButtonGroup.SelectedObject;
        end

        % Value changed function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanged(app, event)
            value = app.FrequencyHzEditField.Value;
            writeline(app.ser, sprintf('sour:wave:freq %d',value))
        end

        % Callback function
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_4.Value;
            if app.CheckBox_4.Value
                writeline(app.ser, 'sour:curr:rang auto')
            end
        end

        % Value changed function: RangeuAEditField
        function RangeuAEditFieldValueChanged(app, event)
            value = app.RangeuAEditField.Value;
            writeline(app.ser, sprintf('sour:curr:rang %de-6',value))
        end

        % Button pushed function: ArmwaveformButton_2
        function ArmwaveformButton_2Pushed(app, event)
            % For DC
            writeline(app.ser, 'sour:wave:abor')
            % Filtering
            if app.OnButton.Value
                writeline(app.ser, 'curr:filt on')
            else
                writeline(app.ser, 'curr:filt off')
            end
            % Inner shield (BLACK)
            if app.GuardButton.Value
                writeline(app.ser, 'outp:ishield guard')
            else
                writeline(app.ser, 'outp:ishield olow')
            end
            % Output low (GREEN)
            if app.EarthButton.Value
                writeline(app.ser, 'outp:ltearth on')
            else
                writeline(app.ser, 'outp:ltearth off')
            end
            % Range
%             if app.CheckBox_5.Value
%                 writeline(app.ser, 'sour:curr:rang auto')
%             else
%                 writeline(app.ser, sprintf('sour:curr:rang %de-6',app.RangeuAEditField.Value))
%             end
            % Compliance
            writeline(app.ser, sprintf('curr:comp %d',app.ComplianceVEditField.Value))
            % Frequency
            writeline(app.ser, sprintf('sour:wave:freq 1e-2'))
            % Amplitude
            writeline(app.ser, sprintf('sour:wave:ampl %de-6', abs(app.AmplitudeEditField.Value)))
            % Duration
            writeline(app.ser, sprintf('sour:wave:dur:time %d',app.DurationEditField.Value))


            % Generation of waveform string
            iscath = app.CathodicButton.Value;

            if iscath 
                str_wave = -1;
            else
                str_wave = 1;
            end
            str_wave
            
            plot(app.UIAxes,[-4:0 0:app.DurationEditField.Value app.DurationEditField.Value+[0 1 2 3 4]],[zeros(1,5) str_wave*ones(1,app.DurationEditField.Value+1)*app.AmplitudeEditField.Value zeros(1,5)])
            if iscath
                ylim(app.UIAxes,[-1.5*abs(app.AmplitudeEditField.Value) 20])
            else
                ylim(app.UIAxes,[-20 1.5*abs(app.AmplitudeEditField.Value)])
            end
            xlim(app.UIAxes,[-4, app.DurationEditField.Value+4])

            writeline(app.ser, sprintf('sour:wave:arb:data %d',str_wave))
            writeline(app.ser, 'sour:wave:func arb0')
            writeline(app.ser, 'sour:wave:arm')
        end

        % Value changed function: CathodicEditField_2
        function CathodicEditField_2ValueChanged(app, event)
            value = app.CathodicEditField_2.Value;
            app.AnodicEditField_2.Value = value;
        end

        % Callback function
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
            if app.CheckBox_5.Value
                writeline(app.ser, 'sour:curr:rang auto')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PulseTab = uitab(app.TabGroup);
            app.PulseTab.Title = 'Pulse';
            app.PolarityButtonGroup = uibuttongroup(app.PulseTab);
            app.PolarityButtonGroup.Title = 'Polarity';
            app.CathodicfirstButton = uitogglebutton(app.PolarityButtonGroup);
            app.CathodicfirstButton.Text = 'Cathodic-first';
            app.CathodicfirstButton.Value = true;
            app.AnodicfirstButton = uitogglebutton(app.PolarityButtonGroup);
            app.AnodicfirstButton.Text = 'Anodic-first';
            app.ButtonGroup_2 = uibuttongroup(app.PolarityButtonGroup);
            app.ButtonGroup_3 = uibuttongroup(app.ButtonGroup_2);
            app.ButtonGroup_4 = uibuttongroup(app.ButtonGroup_2);
            app.ComplianceVLabel = uilabel(app.PulseTab);
            app.ComplianceVLabel.Text = 'Compliance (V)';
            app.CheckBox_3 = uicheckbox(app.PulseTab);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_3.Value = true;
            app.FrequencyHzEditFieldLabel = uilabel(app.PulseTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.InterphasegapEditFieldLabel = uilabel(app.PulseTab);
            app.InterphasegapEditFieldLabel.Text = 'Interphase gap';
            app.InterphasegapEditField = uieditfield(app.PulseTab, 'numeric');
            app.InterphasegapEditField.Editable = 'off';
            app.NumberofcyclesEditFieldLabel = uilabel(app.PulseTab);
            app.NumberofcyclesEditFieldLabel.Text = 'Number of cycles';
            app.NumberofcyclesEditField = uieditfield(app.PulseTab, 'numeric');
            app.NumberofcyclesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofcyclesEditFieldValueChanged, true);
            app.NumberofcyclesEditField.Value = 100;
            app.ORdurationsEditFieldLabel = uilabel(app.PulseTab);
            app.ORdurationsEditFieldLabel.Text = 'OR duration (s)';
            app.ORdurationsEditField = uieditfield(app.PulseTab, 'numeric');
            app.ORdurationsEditField.ValueChangedFcn = createCallbackFcn(app, @ORdurationsEditFieldValueChanged, true);
            app.ORdurationsEditField.Enable = 'off';
            app.PhaseamplitudeuALabel = uilabel(app.PulseTab);
            app.PhaseamplitudeuALabel.Text = 'Phase amplitude (uA)';
            app.CathodicEditFieldLabel = uilabel(app.PulseTab);
            app.CathodicEditFieldLabel.Text = '|Cathodic|';
            app.CathodicEditField = uieditfield(app.PulseTab, 'numeric');
            app.CathodicEditField.Value = 100;
            app.AnodicEditFieldLabel = uilabel(app.PulseTab);
            app.AnodicEditFieldLabel.Text = '|Anodic|';
            app.AnodicEditField = uieditfield(app.PulseTab, 'numeric');
            app.AnodicEditField.Value = 100;
            app.PhasedurationusLabel = uilabel(app.PulseTab);
            app.PhasedurationusLabel.Text = 'Phase duration (us)';
            app.CathodicEditField_2Label = uilabel(app.PulseTab);
            app.CathodicEditField_2Label.Text = 'Cathodic';
            app.CathodicEditField_2 = uieditfield(app.PulseTab, 'numeric');
            app.CathodicEditField_2.ValueChangedFcn = createCallbackFcn(app, @CathodicEditField_2ValueChanged, true);
            app.CathodicEditField_2.Value = 100;
            app.AnodicEditField_2Label = uilabel(app.PulseTab);
            app.AnodicEditField_2Label.Text = 'Anodic';
            app.AnodicEditField_2 = uieditfield(app.PulseTab, 'numeric');
            app.AnodicEditField_2.Editable = 'off';
            app.AnodicEditField_2.Value = 100;
            app.ArmwaveformButton = uibutton(app.PulseTab, 'push');
            app.ArmwaveformButton.ButtonPushedFcn = createCallbackFcn(app, @ArmwaveformButton_2ValueChanged, true);
            app.ArmwaveformButton.Text = 'Arm waveform';
            app.FrequencyHzEditField = uieditfield(app.PulseTab, 'numeric');
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanged, true);
            app.FrequencyHzEditField.Value = 400;
            app.AuthorCeliaFernandezBrilletCodeindevelopmentasofJan2023Label = uilabel(app.PulseTab);
            app.AuthorCeliaFernandezBrilletCodeindevelopmentasofJan2023Label.Text = 'Author: Celia Fernandez Brillet. Code in development as of Jan 2023.';
            app.DCTab = uitab(app.TabGroup);
            app.DCTab.Title = 'DC';
            app.ComplianceVLabel_2 = uilabel(app.DCTab);
            app.ComplianceVLabel_2.Text = 'Compliance (V)';
            app.PolarityButtonGroup_2 = uibuttongroup(app.DCTab);
            app.PolarityButtonGroup_2.Title = 'Polarity';
            app.CathodicButton = uitogglebutton(app.PolarityButtonGroup_2);
            app.CathodicButton.Text = 'Cathodic';
            app.CathodicButton.Value = true;
            app.AnodicButton = uitogglebutton(app.PolarityButtonGroup_2);
            app.AnodicButton.Text = 'Anodic';
            app.ButtonGroup_5 = uibuttongroup(app.PolarityButtonGroup_2);
            app.AmplitudeuALabel = uilabel(app.DCTab);
            app.AmplitudeuALabel.Text = 'Amplitude (uA)';
            app.AmplitudeEditField = uieditfield(app.DCTab, 'numeric');
            app.AmplitudeEditField.Value = 100;
            app.ArmwaveformButton_2 = uibutton(app.DCTab, 'push');
            app.ArmwaveformButton_2.ButtonPushedFcn = createCallbackFcn(app, @ArmwaveformButton_2Pushed, true);
            app.ArmwaveformButton_2.Text = 'Arm waveform';
            app.ButtonGroup_6 = uibuttongroup(app.DCTab);
            app.AmplitudeuALabel_2 = uilabel(app.ButtonGroup_6);
            app.AmplitudeuALabel_2.Text = 'Duration (s)';
            app.DurationEditField = uieditfield(app.ButtonGroup_6, 'numeric');
            app.DurationEditField.Value = 100;
            app.FilterButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterButtonGroupSelectionChanged, true);
            app.FilterButtonGroup.Title = 'Filter';
            app.OnButton = uiradiobutton(app.FilterButtonGroup);
            app.OnButton.Text = 'On';
            app.OffButton = uiradiobutton(app.FilterButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.ComplianceVEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComplianceVEditField.ValueChangedFcn = createCallbackFcn(app, @ComplianceVEditFieldValueChanged, true);
            app.ComplianceVEditField.Value = 100;
            app.InnershieldButtonGroup = uibuttongroup(app.UIFigure);
            app.InnershieldButtonGroup.Title = 'Inner shield';
            app.LowButton = uiradiobutton(app.InnershieldButtonGroup);
            app.LowButton.Text = 'Low';
            app.LowButton.Value = true;
            app.GuardButton = uiradiobutton(app.InnershieldButtonGroup);
            app.GuardButton.Text = 'Guard';
            app.OutputLowButtonGroup = uibuttongroup(app.UIFigure);
            app.OutputLowButtonGroup.Title = 'Output Low';
            app.FloatButton = uiradiobutton(app.OutputLowButtonGroup);
            app.FloatButton.Text = 'Float';
            app.FloatButton.Value = true;
            app.EarthButton = uiradiobutton(app.OutputLowButtonGroup);
            app.EarthButton.Text = 'Earth';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Painted_Chinchilla_PNG_Picture_Clipart.png';
            app.ConnectportButton = uibutton(app.UIFigure, 'push');
            app.ConnectportButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectportButtonValueChanged, true);
            app.ConnectportButton.Text = 'Connect port';
            app.idnButton = uibutton(app.UIFigure, 'push');
            app.idnButton.ButtonPushedFcn = createCallbackFcn(app, @idnButtonValueChanged, true);
            app.idnButton.Enable = 'off';
            app.idnButton.Text = '*idn?';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonValueChanged, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.InitializewaveformButton = uibutton(app.UIFigure, 'push');
            app.InitializewaveformButton.ButtonPushedFcn = createCallbackFcn(app, @InitializewaveformButton_2ValueChanged, true);
            app.InitializewaveformButton.Text = 'Initialize waveform';
            app.AbortwaveformButton = uibutton(app.UIFigure, 'push');
            app.AbortwaveformButton.ButtonPushedFcn = createCallbackFcn(app, @AbortwaveformButtonValueChanged, true);
            app.AbortwaveformButton.Text = 'Abort waveform';
            app.RangeuAEditFieldLabel = uilabel(app.UIFigure);
            app.RangeuAEditFieldLabel.Text = 'Range (uA)';
            app.RangeuAEditField = uieditfield(app.UIFigure, 'numeric');
            app.RangeuAEditField.ValueChangedFcn = createCallbackFcn(app, @RangeuAEditFieldValueChanged, true);
            app.RangeuAEditField.Value = 200;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Waveform Preview')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Current Amplitude (\muA)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Keithley_Stimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end